classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextArea      matlab.ui.control.TextArea
        ClearButton   matlab.ui.control.Button
        EditField_81  matlab.ui.control.NumericEditField
        EditField_80  matlab.ui.control.NumericEditField
        EditField_79  matlab.ui.control.NumericEditField
        EditField_78  matlab.ui.control.NumericEditField
        EditField_77  matlab.ui.control.NumericEditField
        EditField_76  matlab.ui.control.NumericEditField
        EditField_75  matlab.ui.control.NumericEditField
        EditField_74  matlab.ui.control.NumericEditField
        EditField_73  matlab.ui.control.NumericEditField
        EditField_72  matlab.ui.control.NumericEditField
        EditField_71  matlab.ui.control.NumericEditField
        EditField_70  matlab.ui.control.NumericEditField
        EditField_69  matlab.ui.control.NumericEditField
        EditField_68  matlab.ui.control.NumericEditField
        EditField_67  matlab.ui.control.NumericEditField
        EditField_66  matlab.ui.control.NumericEditField
        EditField_65  matlab.ui.control.NumericEditField
        EditField_64  matlab.ui.control.NumericEditField
        EditField_63  matlab.ui.control.NumericEditField
        EditField_62  matlab.ui.control.NumericEditField
        EditField_61  matlab.ui.control.NumericEditField
        EditField_60  matlab.ui.control.NumericEditField
        EditField_59  matlab.ui.control.NumericEditField
        EditField_58  matlab.ui.control.NumericEditField
        EditField_57  matlab.ui.control.NumericEditField
        EditField_56  matlab.ui.control.NumericEditField
        EditField_55  matlab.ui.control.NumericEditField
        EditField_54  matlab.ui.control.NumericEditField
        EditField_53  matlab.ui.control.NumericEditField
        EditField_52  matlab.ui.control.NumericEditField
        EditField_51  matlab.ui.control.NumericEditField
        EditField_50  matlab.ui.control.NumericEditField
        EditField_49  matlab.ui.control.NumericEditField
        EditField_48  matlab.ui.control.NumericEditField
        EditField_47  matlab.ui.control.NumericEditField
        EditField_46  matlab.ui.control.NumericEditField
        EditField_45  matlab.ui.control.NumericEditField
        EditField_44  matlab.ui.control.NumericEditField
        EditField_43  matlab.ui.control.NumericEditField
        EditField_42  matlab.ui.control.NumericEditField
        EditField_41  matlab.ui.control.NumericEditField
        EditField_40  matlab.ui.control.NumericEditField
        EditField_39  matlab.ui.control.NumericEditField
        EditField_38  matlab.ui.control.NumericEditField
        EditField_37  matlab.ui.control.NumericEditField
        EditField_36  matlab.ui.control.NumericEditField
        EditField_35  matlab.ui.control.NumericEditField
        EditField_34  matlab.ui.control.NumericEditField
        EditField_33  matlab.ui.control.NumericEditField
        EditField_32  matlab.ui.control.NumericEditField
        EditField_31  matlab.ui.control.NumericEditField
        EditField_30  matlab.ui.control.NumericEditField
        EditField_29  matlab.ui.control.NumericEditField
        EditField_28  matlab.ui.control.NumericEditField
        EditField_27  matlab.ui.control.NumericEditField
        EditField_26  matlab.ui.control.NumericEditField
        EditField_25  matlab.ui.control.NumericEditField
        EditField_24  matlab.ui.control.NumericEditField
        EditField_23  matlab.ui.control.NumericEditField
        EditField_22  matlab.ui.control.NumericEditField
        EditField_21  matlab.ui.control.NumericEditField
        EditField_20  matlab.ui.control.NumericEditField
        EditField_19  matlab.ui.control.NumericEditField
        EditField_18  matlab.ui.control.NumericEditField
        EditField_17  matlab.ui.control.NumericEditField
        EditField_16  matlab.ui.control.NumericEditField
        EditField_15  matlab.ui.control.NumericEditField
        EditField_14  matlab.ui.control.NumericEditField
        EditField_13  matlab.ui.control.NumericEditField
        EditField_12  matlab.ui.control.NumericEditField
        EditField_11  matlab.ui.control.NumericEditField
        EditField_10  matlab.ui.control.NumericEditField
        EditField_9   matlab.ui.control.NumericEditField
        EditField_8   matlab.ui.control.NumericEditField
        EditField_7   matlab.ui.control.NumericEditField
        EditField_6   matlab.ui.control.NumericEditField
        EditField_5   matlab.ui.control.NumericEditField
        EditField_4   matlab.ui.control.NumericEditField
        EditField_3   matlab.ui.control.NumericEditField
        EditField_2   matlab.ui.control.NumericEditField
        EditField     matlab.ui.control.NumericEditField
        SolveButton   matlab.ui.control.StateButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SolveButton
        function SolveButtonValueChanged(app, event)

            appFolder = fileparts(mfilename('fullpath'));
            addpath(fullfile(appFolder, 'src'));
            value = app.SolveButton.Value;
            sudoku_matrix = zeros(9,9);

            %puts values from ui into a matrix
            for row = 1:9
                for col = 1:9
                    % Construct the tag based on the row and column
                    tagStr = strcat(num2str(row), num2str(col));

                    % Get the numeric field by its tag
                    numField = findobj(app.UIFigure, 'Tag', tagStr);

                    sudoku_matrix(row, col) = numField.Value;
                end
            end

            %solves sudoku 
            [solution, errMsg] = solver(sudoku_matrix);

            if ~isempty(errMsg)
                app.TextArea.Value = errMsg;
            end
            app.SolveButton.Value = 0;

            %displaying the numbers back 
            for row = 1:9
                for col = 1:9
                    tagStr = strcat(num2str(row), num2str(col));
                    numField = findobj(app.UIFigure, 'Tag', tagStr);
                    if ~isempty(numField) && isprop(numField, 'Value')
                        numField.Value = solution(row, col);
                    end
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = ''; %clears error field 

            %adds 0 to all buttons again
            for row = 1:9
                for col = 1:9
                    tagStr = strcat(num2str(row), num2str(col));
                    numField = findobj(app.UIFigure, 'Tag', tagStr);
                    if ~isempty(numField) && isprop(numField, 'Value')
                        numField.Value = 0;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolveButton = uibutton(app.UIFigure, 'state');
            app.SolveButton.ValueChangedFcn = createCallbackFcn(app, @SolveButtonValueChanged, true);
            app.SolveButton.Text = 'Solve!';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 9];
            app.EditField.Tag = '11';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 9];
            app.EditField_2.Tag = '12';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [0 9];
            app.EditField_3.Tag = '13';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [0 9];
            app.EditField_4.Tag = '14';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Limits = [0 9];
            app.EditField_5.Tag = '15';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Limits = [0 9];
            app.EditField_6.Tag = '16';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Limits = [0 9];
            app.EditField_7.Tag = '17';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Limits = [0 9];
            app.EditField_8.Tag = '18';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Limits = [0 9];
            app.EditField_9.Tag = '19';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Limits = [0 9];
            app.EditField_10.Tag = '21';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Limits = [0 9];
            app.EditField_11.Tag = '22';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Limits = [0 9];
            app.EditField_12.Tag = '23';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.Limits = [0 9];
            app.EditField_13.Tag = '24';
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14.Limits = [0 9];
            app.EditField_14.Tag = '25';
            app.EditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_15.Limits = [0 9];
            app.EditField_15.Tag = '26';
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_16.Limits = [0 9];
            app.EditField_16.Tag = '27';
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_17.Limits = [0 9];
            app.EditField_17.Tag = '28';
            app.EditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_18.Limits = [0 9];
            app.EditField_18.Tag = '29';
            app.EditField_19 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_19.Limits = [0 9];
            app.EditField_19.Tag = '31';
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_20.Limits = [0 9];
            app.EditField_20.Tag = '32';
            app.EditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_21.Limits = [0 9];
            app.EditField_21.Tag = '33';
            app.EditField_22 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_22.Limits = [0 9];
            app.EditField_22.Tag = '34';
            app.EditField_23 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_23.Limits = [0 9];
            app.EditField_23.Tag = '35';
            app.EditField_24 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_24.Limits = [0 9];
            app.EditField_24.Tag = '36';
            app.EditField_25 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_25.Limits = [0 9];
            app.EditField_25.Tag = '37';
            app.EditField_26 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_26.Limits = [0 9];
            app.EditField_26.Tag = '38';
            app.EditField_27 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_27.Limits = [0 9];
            app.EditField_27.Tag = '39';
            app.EditField_28 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_28.Limits = [0 9];
            app.EditField_28.Tag = '41';
            app.EditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_29.Limits = [0 9];
            app.EditField_29.Tag = '42';
            app.EditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_30.Limits = [0 9];
            app.EditField_30.Tag = '43';
            app.EditField_31 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_31.Limits = [0 9];
            app.EditField_31.Tag = '44';
            app.EditField_32 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_32.Limits = [0 9];
            app.EditField_32.Tag = '45';
            app.EditField_33 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_33.Limits = [0 9];
            app.EditField_33.Tag = '46';
            app.EditField_34 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_34.Limits = [0 9];
            app.EditField_34.Tag = '47';
            app.EditField_35 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_35.Limits = [0 9];
            app.EditField_35.Tag = '48';
            app.EditField_36 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_36.Limits = [0 9];
            app.EditField_36.Tag = '49';
            app.EditField_37 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_37.Limits = [0 9];
            app.EditField_37.Tag = '51';
            app.EditField_38 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_38.Limits = [0 9];
            app.EditField_38.Tag = '52';
            app.EditField_39 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_39.Limits = [0 9];
            app.EditField_39.Tag = '53';
            app.EditField_40 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_40.Limits = [0 9];
            app.EditField_40.Tag = '54';
            app.EditField_41 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_41.Limits = [0 9];
            app.EditField_41.Tag = '55';
            app.EditField_42 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_42.Limits = [0 9];
            app.EditField_42.Tag = '56';
            app.EditField_43 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_43.Limits = [0 9];
            app.EditField_43.Tag = '57';
            app.EditField_44 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_44.Limits = [0 9];
            app.EditField_44.Tag = '58';
            app.EditField_45 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_45.Limits = [0 9];
            app.EditField_45.Tag = '59';
            app.EditField_46 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_46.Limits = [0 9];
            app.EditField_46.Tag = '61';
            app.EditField_47 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_47.Limits = [0 9];
            app.EditField_47.Tag = '62';
            app.EditField_48 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_48.Limits = [0 9];
            app.EditField_48.Tag = '63';
            app.EditField_49 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_49.Limits = [0 9];
            app.EditField_49.Tag = '64';
            app.EditField_50 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_50.Limits = [0 9];
            app.EditField_50.Tag = '65';
            app.EditField_51 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_51.Limits = [0 9];
            app.EditField_51.Tag = '66';
            app.EditField_52 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_52.Limits = [0 9];
            app.EditField_52.Tag = '67';
            app.EditField_53 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_53.Limits = [0 9];
            app.EditField_53.Tag = '68';
            app.EditField_54 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_54.Limits = [0 9];
            app.EditField_54.Tag = '69';
            app.EditField_55 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_55.Limits = [0 9];
            app.EditField_55.Tag = '71';
            app.EditField_56 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_56.Limits = [0 9];
            app.EditField_56.Tag = '72';
            app.EditField_57 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_57.Limits = [0 9];
            app.EditField_57.Tag = '73';
            app.EditField_58 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_58.Limits = [0 9];
            app.EditField_58.Tag = '74';
            app.EditField_59 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_59.Limits = [0 9];
            app.EditField_59.Tag = '75';
            app.EditField_60 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_60.Limits = [0 9];
            app.EditField_60.Tag = '76';
            app.EditField_61 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_61.Limits = [0 9];
            app.EditField_61.Tag = '77';
            app.EditField_62 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_62.Limits = [0 9];
            app.EditField_62.Tag = '78';
            app.EditField_63 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_63.Limits = [0 9];
            app.EditField_63.Tag = '79';
            app.EditField_64 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_64.Limits = [0 9];
            app.EditField_64.Tag = '81';
            app.EditField_65 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_65.Limits = [0 9];
            app.EditField_65.Tag = '82';
            app.EditField_66 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_66.Limits = [0 9];
            app.EditField_66.Tag = '83';
            app.EditField_67 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_67.Limits = [0 9];
            app.EditField_67.Tag = '84';
            app.EditField_68 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_68.Limits = [0 9];
            app.EditField_68.Tag = '85';
            app.EditField_69 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_69.Limits = [0 9];
            app.EditField_69.Tag = '86';
            app.EditField_70 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_70.Limits = [0 9];
            app.EditField_70.Tag = '87';
            app.EditField_71 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_71.Limits = [0 9];
            app.EditField_71.Tag = '88';
            app.EditField_72 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_72.Limits = [0 9];
            app.EditField_72.Tag = '89';
            app.EditField_73 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_73.Limits = [0 9];
            app.EditField_73.Tag = '91';
            app.EditField_74 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_74.Limits = [0 9];
            app.EditField_74.Tag = '92';
            app.EditField_75 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_75.Limits = [0 9];
            app.EditField_75.Tag = '93';
            app.EditField_76 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_76.Limits = [0 9];
            app.EditField_76.Tag = '94';
            app.EditField_77 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_77.Limits = [0 9];
            app.EditField_77.Tag = '95';
            app.EditField_78 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_78.Limits = [0 9];
            app.EditField_78.Tag = '96';
            app.EditField_79 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_79.Limits = [0 9];
            app.EditField_79.Tag = '97';
            app.EditField_80 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_80.Limits = [0 9];
            app.EditField_80.Tag = '98';
            app.EditField_81 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_81.Limits = [0 9];
            app.EditField_81.Tag = '99';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end